classdef countingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TotalCountNum            matlab.ui.control.Label
        CurrentCountNum          matlab.ui.control.Label
        ResetCurrentCountButton  matlab.ui.control.Button
        DecreaseCountButton      matlab.ui.control.Button
        IncreaseCountButton      matlab.ui.control.Button
        MatthewEleazarLabel      matlab.ui.control.Label
        CountingAppLabel         matlab.ui.control.Label
        TotalCountLabel          matlab.ui.control.Label
        CurrentCountLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IncreaseCountButton
        function IncreaseCountButtonPushed(app, event)
            app.TotalCountNum.Text = num2str(str2double(app.TotalCountNum.Text) + 1)
            app.CurrentCountNum.Text = num2str(str2double(app.CurrentCountNum.Text) + 1)

        end

        % Button pushed function: DecreaseCountButton
        function DecreaseCountButtonPushed(app, event)
            app.TotalCountNum.Text = num2str(str2double(app.TotalCountNum.Text) - 1)
            app.CurrentCountNum.Text = num2str(str2double(app.CurrentCountNum.Text) - 1)
        end

        % Button pushed function: ResetCurrentCountButton
        function ResetCurrentCountButtonPushed(app, event)
            app.CurrentCountNum.Text = num2str(str2double(app.CurrentCountNum.Text) - str2double(app.CurrentCountNum.Text))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CurrentCountLabel = uilabel(app.UIFigure);
            app.CurrentCountLabel.Text = 'Current Count';
            app.TotalCountLabel = uilabel(app.UIFigure);
            app.TotalCountLabel.Text = 'Total Count';
            app.CountingAppLabel = uilabel(app.UIFigure);
            app.CountingAppLabel.Text = 'Counting App';
            app.MatthewEleazarLabel = uilabel(app.UIFigure);
            app.MatthewEleazarLabel.Text = 'Matthew Eleazar';
            app.IncreaseCountButton = uibutton(app.UIFigure, 'push');
            app.IncreaseCountButton.ButtonPushedFcn = createCallbackFcn(app, @IncreaseCountButtonPushed, true);
            app.IncreaseCountButton.Text = 'Increase Count (↑)';
            app.DecreaseCountButton = uibutton(app.UIFigure, 'push');
            app.DecreaseCountButton.ButtonPushedFcn = createCallbackFcn(app, @DecreaseCountButtonPushed, true);
            app.DecreaseCountButton.Text = 'Decrease Count (↓)';
            app.ResetCurrentCountButton = uibutton(app.UIFigure, 'push');
            app.ResetCurrentCountButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCurrentCountButtonPushed, true);
            app.ResetCurrentCountButton.Text = 'Reset Current Count';
            app.CurrentCountNum = uilabel(app.UIFigure);
            app.CurrentCountNum.Text = '0';
            app.TotalCountNum = uilabel(app.UIFigure);
            app.TotalCountNum.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = countingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end