classdef myCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AnswerVal             matlab.ui.control.Label
        Answer                matlab.ui.control.Label
        MatthewEleazarLabel   matlab.ui.control.Label
        Calculate             matlab.ui.control.Button
        OperationButtonGroup  matlab.ui.container.ButtonGroup
        Exponential           matlab.ui.control.RadioButton
        Division              matlab.ui.control.RadioButton
        Multiplication        matlab.ui.control.RadioButton
        Subtraction           matlab.ui.control.RadioButton
        Addition              matlab.ui.control.RadioButton
        Num2                  matlab.ui.control.NumericEditField
        EditField_2Label      matlab.ui.control.Label
        Num1                  matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        SecondNumberLabel     matlab.ui.control.Label
        FirstNumberLabel      matlab.ui.control.Label
        MyCalculatorAppLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Calculate
        function CalculateButtonPushed(app, event)
            num1 = app.Num1.Value;
            num2 = app.Num2.Value;

            if app.Addition.Value
                app.AnswerVal.Text = num2str(num1 + num2);
            end

            if app.Subtraction.Value
                app.AnswerVal.Text = num2str(num1 - num2);
            end

            if app.Multiplication.Value
                app.AnswerVal.Text = num2str(num1 * num2);
            end

            if app.Division.Value
                app.AnswerVal.Text = num2str(num1 / num2);
            end

            if app.Exponential.Value
                app.AnswerVal.Text = num2str((num1) ^ num2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MyCalculatorAppLabel = uilabel(app.UIFigure);
            app.MyCalculatorAppLabel.Text = 'My Calculator App';
            app.FirstNumberLabel = uilabel(app.UIFigure);
            app.FirstNumberLabel.Text = 'First Number';
            app.SecondNumberLabel = uilabel(app.UIFigure);
            app.SecondNumberLabel.Text = 'Second Number';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Num1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '';
            app.Num2 = uieditfield(app.UIFigure, 'numeric');
            app.OperationButtonGroup = uibuttongroup(app.UIFigure);
            app.OperationButtonGroup.Title = 'Operation';
            app.Addition = uiradiobutton(app.OperationButtonGroup);
            app.Addition.Text = 'Addition (+)';
            app.Addition.Value = true;
            app.Subtraction = uiradiobutton(app.OperationButtonGroup);
            app.Subtraction.Text = 'Subtraction (-)';
            app.Multiplication = uiradiobutton(app.OperationButtonGroup);
            app.Multiplication.Text = 'Multiplication (*)';
            app.Division = uiradiobutton(app.OperationButtonGroup);
            app.Division.Text = 'Division (/)';
            app.Exponential = uiradiobutton(app.OperationButtonGroup);
            app.Exponential.Text = 'Exponential (^)';
            app.Calculate = uibutton(app.UIFigure, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.Calculate.Text = 'Calculate';
            app.MatthewEleazarLabel = uilabel(app.UIFigure);
            app.MatthewEleazarLabel.Text = 'Matthew Eleazar';
            app.Answer = uilabel(app.UIFigure);
            app.Answer.Text = 'Answer';
            app.AnswerVal = uilabel(app.UIFigure);
            app.AnswerVal.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end